classdef testCase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout4              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        ImportTab                matlab.ui.container.Tab
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        AreamEditField           matlab.ui.control.NumericEditField
        AreamEditFieldLabel      matlab.ui.control.Label
        ErrorLabel               matlab.ui.control.Label
        SearchFileButton         matlab.ui.control.Button
        bagFile                  matlab.ui.control.EditField
        content2                 matlab.ui.control.Label
        LoadDataButton           matlab.ui.control.Button
        DepthImg                 matlab.ui.control.UIAxes
        RGBImg                   matlab.ui.control.UIAxes
        ExamineTab               matlab.ui.container.Tab
        TabGroup3                matlab.ui.container.TabGroup
        DepthTab                 matlab.ui.container.Tab
        GridLayout5              matlab.ui.container.GridLayout
        GridLayout6              matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        ResetCorrSpinButton      matlab.ui.control.Button
        timesMedianSpinner       matlab.ui.control.Spinner
        timesmedianSpinnerLabel  matlab.ui.control.Label
        Y_1Label_2               matlab.ui.control.Label
        X_1Label_2               matlab.ui.control.Label
        StepSpinnerLabel         matlab.ui.control.Label
        CorrectionsLabel         matlab.ui.control.Label
        XCorrSpinner_1           matlab.ui.control.Spinner
        X_1Label                 matlab.ui.control.Label
        YCorrSpinner_1           matlab.ui.control.Spinner
        XCorrSpinner_2           matlab.ui.control.Spinner
        YCorrSpinner_2           matlab.ui.control.Spinner
        StepCorrSpinner          matlab.ui.control.Spinner
        ApplyButton              matlab.ui.control.Button
        Y_1Label                 matlab.ui.control.Label
        OverlayRGBImg            matlab.ui.control.UIAxes
        CorrDepthImg3d           matlab.ui.control.UIAxes
        CorrDepthImg             matlab.ui.control.UIAxes
        ImageTab                 matlab.ui.container.Tab
        LAITab                   matlab.ui.container.Tab
        ExportTab                matlab.ui.container.Tab
        GetDataButton            matlab.ui.control.Button
        ExportasDropDown         matlab.ui.control.DropDown
        ExportasDropDownLabel    matlab.ui.control.Label
        madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel  matlab.ui.control.Label
        Hyperlink                matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
        rawBagData      % The data imported from the .bag file untouched
        originalRGBImg  % The original RGB image extracted from the .bag file
        scaledRGBImg    % RGB image scaled with depth view

        originalDepthImg        % The original Depth image extracted from the .bag file
        sizeOriginalDepthImg    % The width (2) and height(1) data
        correctedDepthImg         % Depth image cropped with rectangle

        timesMedian % timesMedian value

        filePath % The path to the file
        areaMSquare % The given area of the data
    end
    
    methods (Access = private)
        
        function ComputeData(app, bagData)

            curSelColor = select(bagData,'Topic','/device_0/sensor_1/Color_0/image/data');
            allImColor = readMessages(curSelColor, 'DataFormat', 'struct');
            app.originalRGBImg = double(rosReadImage(allImColor{1}))/255.0;

            curSelDepth = select(bagData,'Topic','/device_0/sensor_0/Depth_0/image/data');
            allImDepth = readMessages(curSelDepth, 'DataFormat', 'struct');
            imDepth = double(rosReadImage(allImDepth{1}));
            %imDepthNormalised = imDepth / max(max(imDepth));

            curSelDepth = select(bagData,'Topic','/device_0/sensor_0/option/Depth_Units/value');
            depthVal = readMessages(curSelDepth, 'DataFormat', 'struct');
            imDepthReal = imDepth .* depthVal{1}.Data; 
            imDepthReal(imDepthReal == 0) = NaN; % Change the zero data with NaN.
            
            app.originalDepthImg = imDepthReal;

            % Compute data for cropping
            app.sizeOriginalDepthImg = size(app.originalDepthImg);
        end
        
        function DispImg(app, img, parent)
            imshow(img, 'Parent',parent);
        end

        function DispImg3d(app, img, parent)
            surf(img, "EdgeColor","none", 'Parent', parent)
            %scatfig.Children(2).Parent = parent
        end

        function ResetCropValues(app)
            try
                app.XCorrSpinner_1.Value = 0.0;
                app.YCorrSpinner_1.Value = 0.0;

                app.XCorrSpinner_2.Value = app.sizeOriginalDepthImg(2);
                app.YCorrSpinner_2.Value = app.sizeOriginalDepthImg(1);

                app.correctedDepthImg = app.originalDepthImg;

                app.timesMedian = 3;
                ApplyMedianFilter(app);

                DispImg(app, app.correctedDepthImg, app.CorrDepthImg);
                DispImg3d(app, app.correctedDepthImg, app.CorrDepthImg3d)

                %DispImg(app, NaN, app.OverlayRGBImg)

                cla(app.OverlayRGBImg, 'reset') 

            catch
                disp("No value loaded");
            end
        end
        
        function ApplyMedianFilter(app)
            app.timesMedian = app.timesMedianSpinner.Value;
            % Haal de outliners er van af. We nemen x maal de mediaan
            medianImage = app.timesMedian*median(app.correctedDepthImg,'all', 'omitmissing');
            % omitmissing zal ervoor zorgen dat we NaN negeren
            app.correctedDepthImg(medianImage <= abs(app.correctedDepthImg)) = NaN;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadBatFile(app, event)
            % reset the current values
            clc;
            
            %path = "ImgLoad/";
            file = app.bagFile.Value;
            
            errorFlag = 0;

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.rawBagData = rosbag(file);
            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.areaMSquare = app.AreamEditField.Value;
                disp(app.areaMSquare)
            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                ComputeData(app, app.rawBagData); % Compute the image as rgb and depth image and return this
            catch
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Can't compute data.";
                end
            end 
            % https://www.mathworks.com/matlabcentral/answers/418893-how-to-display-an-image-in-ui-figure-on-app-designer
            %imshow(app.originalRGBImg, 'Parent',app.RGBImg);
            DispImg(app, app.originalRGBImg, app.RGBImg);
            %imshow(app.originalDepthImg, 'Parent',app.DepthImg);
            DispImg(app, app.originalDepthImg, app.DepthImg);

            % Set the crop values
            app.ResetCropValues();

        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                [file,path] = uigetfile('*.bag');
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    disp(['User selected ', fullfile(path,file)]);
                    app.filePath = fullfile(path,file); %Transfer filepath
                    app.bagFile.Value = app.filePath; % set filepath in editable field
                end
            catch
                disp("something wrong with the file");
            end
        end

        % Button pushed function: ResetCorrSpinButton
        function ResetCorrSpinButtonPushed(app, event)
            % Reset the correction values to 0
            app.ResetCropValues();
            app.StepCorrSpinner.Value = 1.0;
            
            % Display the original image again
            %DispImg(app, app.originalRGBImg, app.RGBImg)
            %DispImg(app, app.originalDepthImg, app.DepthImg)
            DispImg(app, app.originalDepthImg, app.CorrDepthImg);
            DispImg3d(app, app.originalDepthImg, app.CorrDepthImg3d)
        end

        % Value changed function: StepCorrSpinner
        function StepCorrSpinnerValueChanged(app, event)
            value = app.StepCorrSpinner.Value;
            % change the step of XCorrSpinner and YCorrSpinner
            app.XCorrSpinner_1.Step = value;
            app.YCorrSpinner_1.Step = value;
            app.XCorrSpinner_2.Step = value;
            app.YCorrSpinner_2.Step = value;
        end

        % Value changed function: XCorrSpinner_1, XCorrSpinner_2, 
        % ...and 3 other components
        function CorrectionValueChanged(app, event)
            valueX1 = app.XCorrSpinner_1.Value;
            valueY1 = app.YCorrSpinner_1.Value;
            valueX2 = app.XCorrSpinner_2.Value;
            valueY2 = app.YCorrSpinner_2.Value;

            % Determine the rectangle we want to crop
            rect = [valueX1 valueY1 valueX2 valueY2];

           try
                %apply the crop
                app.correctedDepthImg = imcrop(app.originalDepthImg, rect);

                % Apply outliner filter
                ApplyMedianFilter(app);

                % display both
                DispImg(app, app.correctedDepthImg, app.CorrDepthImg);
           catch
                % display error
           end

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Will process the changes to RGB value and resize so they
            % match in size
            try
                [rows, columns] = size(app.correctedDepthImg);
                app.scaledRGBImg = imresize(app.originalRGBImg, [rows columns]);

                temp = app.scaledRGBImg.*app.correctedDepthImg;
                DispImg(app,temp, app.OverlayRGBImg);
                DispImg3d(app, app.correctedDepthImg, app.CorrDepthImg3d);
            catch
                disp("No file loaded");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://github.com/RobbeElsermans/LAI_detection';
            app.Hyperlink.Text = 'GitHub';
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel = uilabel(app.GridLayout4);
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel.Text = 'made by: Robbe Elsermans, Thomas Kramp and Ahmad Shakleya';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Tooltip = {'Import data to work with.'};
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.RGBImg = uiaxes(app.GridLayout);
            title(app.RGBImg, 'RGB')
            app.RGBImg.XTick = [];
            app.RGBImg.YTick = [];
            app.DepthImg = uiaxes(app.GridLayout);
            title(app.DepthImg, 'Depth')
            app.DepthImg.XTick = [];
            app.DepthImg.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBatFile, true);
            app.LoadDataButton.Text = 'Load Data';
            app.content2 = uilabel(app.GridLayout2);
            app.content2.Text = 'Path';
            app.bagFile = uieditfield(app.GridLayout2, 'text');
            app.bagFile.Placeholder = 'c:/img1.bat';
            app.SearchFileButton = uibutton(app.GridLayout2, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.ErrorLabel = uilabel(app.GridLayout2);
            app.ErrorLabel.Tag = 'FileImportError';
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.AreamEditFieldLabel = uilabel(app.GridLayout2);
            app.AreamEditFieldLabel.Text = 'Area m²';
            app.AreamEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AreamEditField.Limits = [0 Inf];
            app.AreamEditField.ValueDisplayFormat = '%0.0f m²';
            app.AreamEditField.Placeholder = 'm²';
            app.ExamineTab = uitab(app.TabGroup);
            app.ExamineTab.Tooltip = {''};
            app.ExamineTab.Title = 'Examine';
            app.TabGroup3 = uitabgroup(app.ExamineTab);
            app.DepthTab = uitab(app.TabGroup3);
            app.DepthTab.Tooltip = {''};
            app.DepthTab.Title = 'Depth';
            app.GridLayout5 = uigridlayout(app.DepthTab);
            app.CorrDepthImg = uiaxes(app.GridLayout5);
            title(app.CorrDepthImg, 'Correction Depth')
            app.CorrDepthImg.XTick = [];
            app.CorrDepthImg.YTick = [];
            app.CorrDepthImg3d = uiaxes(app.GridLayout5);
            title(app.CorrDepthImg3d, '3D depth')
            app.CorrDepthImg3d.XTick = [];
            app.CorrDepthImg3d.YTick = [];
            app.OverlayRGBImg = uiaxes(app.GridLayout5);
            title(app.OverlayRGBImg, 'Depth + RGB overlay')
            app.OverlayRGBImg.XTick = [];
            app.OverlayRGBImg.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout5);
            app.Y_1Label = uilabel(app.GridLayout3);
            app.Y_1Label.Text = 'Y_1:';
            app.ApplyButton = uibutton(app.GridLayout3, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Tooltip = {'Apply the depth on the picture for comparison'};
            app.ApplyButton.Text = 'Apply';
            app.StepCorrSpinner = uispinner(app.GridLayout3);
            app.StepCorrSpinner.Limits = [1 Inf];
            app.StepCorrSpinner.ValueChangedFcn = createCallbackFcn(app, @StepCorrSpinnerValueChanged, true);
            app.StepCorrSpinner.Value = 1;
            app.YCorrSpinner_2 = uispinner(app.GridLayout3);
            app.YCorrSpinner_2.Limits = [0 Inf];
            app.YCorrSpinner_2.ValueChangedFcn = createCallbackFcn(app, @CorrectionValueChanged, true);
            app.XCorrSpinner_2 = uispinner(app.GridLayout3);
            app.XCorrSpinner_2.Limits = [0 Inf];
            app.XCorrSpinner_2.ValueChangedFcn = createCallbackFcn(app, @CorrectionValueChanged, true);
            app.YCorrSpinner_1 = uispinner(app.GridLayout3);
            app.YCorrSpinner_1.Limits = [0 Inf];
            app.YCorrSpinner_1.ValueChangedFcn = createCallbackFcn(app, @CorrectionValueChanged, true);
            app.X_1Label = uilabel(app.GridLayout3);
            app.X_1Label.Text = 'X_1:';
            app.XCorrSpinner_1 = uispinner(app.GridLayout3);
            app.XCorrSpinner_1.Limits = [0 Inf];
            app.XCorrSpinner_1.ValueChangedFcn = createCallbackFcn(app, @CorrectionValueChanged, true);
            app.CorrectionsLabel = uilabel(app.GridLayout3);
            app.CorrectionsLabel.Text = 'Corrections';
            app.StepSpinnerLabel = uilabel(app.GridLayout3);
            app.StepSpinnerLabel.Text = 'Step';
            app.X_1Label_2 = uilabel(app.GridLayout3);
            app.X_1Label_2.Text = 'X_2:';
            app.Y_1Label_2 = uilabel(app.GridLayout3);
            app.Y_1Label_2.Text = 'Y_2:';
            app.timesmedianSpinnerLabel = uilabel(app.GridLayout3);
            app.timesmedianSpinnerLabel.Text = 'times median';
            app.timesMedianSpinner = uispinner(app.GridLayout3);
            app.timesMedianSpinner.Step = 0.01;
            app.timesMedianSpinner.Limits = [0.1 Inf];
            app.timesMedianSpinner.ValueChangedFcn = createCallbackFcn(app, @CorrectionValueChanged, true);
            app.timesMedianSpinner.Tooltip = {'Outliners are filtered out by clearing x times median values.'};
            app.timesMedianSpinner.Value = 3;
            app.ResetCorrSpinButton = uibutton(app.GridLayout3, 'push');
            app.ResetCorrSpinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCorrSpinButtonPushed, true);
            app.ResetCorrSpinButton.Text = 'Reset';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ImageTab = uitab(app.TabGroup3);
            app.ImageTab.Title = 'Image';
            app.LAITab = uitab(app.TabGroup3);
            app.LAITab.Tooltip = {'Compute Leave Area Index and otpion to finetune the used equasions.'};
            app.LAITab.Title = 'LAI';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Tooltip = {'Export the data'};
            app.ExportTab.Title = 'Export';
            app.ExportasDropDownLabel = uilabel(app.ExportTab);
            app.ExportasDropDownLabel.Text = 'Export as';
            app.ExportasDropDown = uidropdown(app.ExportTab);
            app.GetDataButton = uibutton(app.ExportTab, 'push');
            app.GetDataButton.Text = 'Get Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testCase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end